classdef VCGapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        TabGroup                 matlab.ui.container.TabGroup
        DTab_2                   matlab.ui.container.Tab
        DplaneLabel              matlab.ui.control.Label
        threedimUIAxes           matlab.ui.control.UIAxes
        DTab                     matlab.ui.container.Tab
        DplanesLabel             matlab.ui.control.Label
        frontal                  matlab.ui.control.UIAxes
        sagital                  matlab.ui.control.UIAxes
        transversal              matlab.ui.control.UIAxes
        Lamp                     matlab.ui.control.Lamp
        DataloadedLabel          matlab.ui.control.Label
        StatsTab                 matlab.ui.container.Tab
        GridLayout2              matlab.ui.container.GridLayout
        ECGleadsPanel            matlab.ui.container.Panel
        GridLayout               matlab.ui.container.GridLayout
        statsUIAxes1             matlab.ui.control.UIAxes
        statsUIAxes2             matlab.ui.control.UIAxes
        statsUIAxes3             matlab.ui.control.UIAxes
        statsUIAxes4             matlab.ui.control.UIAxes
        statsUIAxes5             matlab.ui.control.UIAxes
        statsUIAxes6             matlab.ui.control.UIAxes
        statsCheckBoxBiphasic    matlab.ui.control.CheckBox
        BiphasicPwaveLabel       matlab.ui.control.Label
        InleadsLabel             matlab.ui.control.Label
        leadsLabel               matlab.ui.control.Label
        CurrentplotSpinnerLabel  matlab.ui.control.Label
        CurrentplotSpinner       matlab.ui.control.Spinner
        ViewDropDownLabel        matlab.ui.control.Label
        ViewDropDown             matlab.ui.control.DropDown
        LinetypeDropDownLabel    matlab.ui.control.Label
        LinetypeDropDown         matlab.ui.control.DropDown
    end

    
    properties (Access = public)
        % public properties
        XML;
        xmlfiles;
        linetype=["ro","r.","r"];
        paxis=[-250 250 -250 250 -250 250];
        qrsaxis=[-1000 1100 -1000 1100 -1000 1100];
        allaxis=[-1000 1100 -1000 1100 -1000 1100];
        VCG_IDT;
        str = 1100;
        
        %initial values
        On=1;
        Off=600;
        ltindex=1;
        axistype=[-1000 1100 -1000 1100 -1000 1100];
    end
    
    
    methods (Access = public)
        function calcVCG(app)
            
            app.XML=XMLECGParser("S:\Testdata\" + convertCharsToStrings(app.xmlfiles(app.CurrentplotSpinner.Value).name));
            
            ecg = app.XML.MedianECG.ecg8;
            
            % Construct inverse dower transform matrix
            T = [ 0.156 -0.010 -0.172 -0.074 0.122 0.231 0.239 0.194  ;
                -0.227 0.887 0.057 -0.019 -0.106 -0.022 0.041 0.048  ;
                0.022  0.102 -0.229 -0.310 -0.246 -0.063 0.055 0.108  ]';
            
            % Invers Dower
            app.VCG_IDT = ecg*T;
        end
        
        function plot2d(app)
            plot(app.frontal,app.VCG_IDT(app.On:app.Off,1),app.VCG_IDT(app.On:app.Off,3),app.linetype(app.ltindex)) %XY
            plot(app.sagital,app.VCG_IDT(app.On:app.Off,3),-app.VCG_IDT(app.On:app.Off,2),app.linetype(app.ltindex)) %YZ
            plot(app.transversal,app.VCG_IDT(app.On:app.Off,1),-app.VCG_IDT(app.On:app.Off,2),app.linetype(app.ltindex)) %XZ
            
            axis(app.frontal,app.axistype);
            axis(app.sagital,app.axistype);
            axis(app.transversal,app.axistype);
        end
        
        function plot3d(app)
            
            
            plot3(app.threedimUIAxes,[-app.str app.str],[0 0],[0 0],'k',[0 0],[-app.str app.str],[0 0],'k',[0 0],[0 0],[-app.str app.str],'k'); % plotter et kryds i 3D plottet
            text(app.threedimUIAxes,app.str,0,0,'Left');
            text(app.threedimUIAxes,-app.str,0,0,'Right');
            text(app.threedimUIAxes,0,app.str,0,'Posterior');
            text(app.threedimUIAxes,0,-app.str,0,'Anterior');
            text(app.threedimUIAxes,0,0,app.str,'Superior');
            text(app.threedimUIAxes,0,0,-app.str,'Inferior');
            
            hold(app.threedimUIAxes,'on');
            
            plot3(app.threedimUIAxes,app.VCG_IDT(app.On:app.Off,1),app.VCG_IDT(app.On:app.Off,3),-app.VCG_IDT(app.On:app.Off,2),app.linetype(app.ltindex))
            
            axis(app.threedimUIAxes,app.axistype);
            axis(app.threedimUIAxes,'square');    
            
            plotPrecordialElectrodes(app);
            plotFrontalElectrodes(app);
            hold(app.threedimUIAxes,'off');
            %app.threedimUIAxes.View; 
        end
        
        function plotStatsTab(app)
            title(app.statsUIAxes1,"III")
            plot(app.statsUIAxes1,app.XML.MedianECG.III)
            grid(app.statsUIAxes1,"on")
            
            title(app.statsUIAxes2,"aVF")
            plot(app.statsUIAxes2,app.XML.MedianECG.aVF)
            grid(app.statsUIAxes2,"on")
            
            title(app.statsUIAxes3,"II")
            plot(app.statsUIAxes3,app.XML.MedianECG.II)
            grid(app.statsUIAxes3,"on")
            
            title(app.statsUIAxes4,"aVR")
            plot(app.statsUIAxes4,app.XML.MedianECG.aVR)
            grid(app.statsUIAxes4,"on")
            
            title(app.statsUIAxes5,"aVL")
            plot(app.statsUIAxes5,app.XML.MedianECG.aVL)
            grid(app.statsUIAxes5,"on")
            
            title(app.statsUIAxes6,"V1")
            plot(app.statsUIAxes6,app.XML.MedianECG.V1)
            grid(app.statsUIAxes6,"on")
            
            [p_iab, biphasic_p_wave, sum_p_loop, sum_p_inv_loop] = pWave_regressionAndDetection(app,app.XML.MedianECG.ECG12Leads,app.XML.TestInfo.POnset,app.XML.TestInfo.POffset);
            
            if(~isempty(find(biphasic_p_wave,1)))
                app.statsCheckBoxBiphasic.Value = 1;
                app.leadsLabel.Text = int2str(find(biphasic_p_wave));
            end
            
        end
        
        function [p_iab, biphasic_p_wave, sum_p_loop, sum_p_inv_loop] = pWave_regressionAndDetection(app,ecg,POnset,POffset)
        % p_iab kan vÃ¦re 0 eller 1 og indikere om subjekt har partiel IAB. 
        % biphasic_p_wave er en 1*M vektor og angiver om p-bÃ¸lgen er bifasisk i henholdvis ecg(:,1), ecg(:,2) osv....
        % sum_p_loop er den den integrerede p-loop(den positive del) ift regressionslinjen i enheden [mikroV*mS]
        % sum_p_inv_loop er den den integrerede p'-loop(den negative del) ift regressionslinjen i enheden [mikroV*mS]
        
        P=[POnset POffset];       % Vektor med P_onset og P_Offset
        
        [r,c] = size(ecg);
        
        % Her kÃ¸res et loop for hver lead. eks. Hvis nr = 1, analyseres lead II (da ecg(;,1) er lead II).  
        for nr=1:1:c                            % c er antallet af leads (kolonner) 
        % Definere p-loop
        P_loop = ecg(P(1,1):P(1,2),nr);         % det oprindelige p-loop for given lead
        
        % Beregning af ligningen for linjen mellem 2 punkter.  
        coefficients = polyfit([P(1,1), P(1,2)], [ecg(P(1,1),nr), ecg(P(1,2),nr)], 1);  %funktion polyfit(x,y,degree) finder koeficienter for ligning mellem 2 punkter. 
        % x-koordinaterne er p_onset og p_offset, y er ekg'et til x'erne, og antal
        % frihedsgrader er 1 da det er imellem 2 punkter. 
        a(nr) = coefficients (1);
        b(nr) = coefficients (2);
        
        % FratrÃ¦kker overstÃ¥ende funktion fra det oprindelindelige p-loop.
        % SÃ¥ledes er P_onset = 0 og P_offset = 0. 
        P_ecg_aligned = P_loop;             %P_ecg_aligned er P-loopet hvor regressionen er fratrukket
        for i=1:1:length(P_loop)
           P_ecg_aligned(i)= P_ecg_aligned(i)-(a(nr)*(P(1,1)+i-1)+b(nr)); %her fratrÃ¦kkes regressionen for alle punkter i p-loopet.   
        end
        
        %kan plottes med fÃ¸lgende udkommenterede: (figur 1 er lead II, figur 2 er lead aVF, figur 3 er lead III)
        %figure(nr), subplot(2,1,1)          
        % plot(P_loop)
        % hold on 
        % t=[1:length(P_loop)];
        % x= [P(1,1):P(1,2)];
        % plot(t,a(nr)*x+b(nr))
        % legend('oprindelige p-loop')
        % subplot(2,1,2)
        % plot(P_ecg_aligned)
        % legend('modificeret p-loop uden hÃ¦ldning') %note: p_onset og p_offset er nu det samme efter fratrÃ¦kning af regressionen. 
        
        % beregning af integralet af p-loopet
        sum_p_loop(nr) = 0;                             % Integralet af P
        sum_p_inv_loop(nr) = 0;                         % integrallet af den negative P
        for i=2:1:length(P_ecg_aligned)
        % hvis p-loop efter fratrÃ¦kning af regression
        % er stÃ¸rre end 0, sÃ¥ summeres den (med trapexoid integrale) i variablen sum_p_loop. 
        % her divideres med samplefrekvensen for at fÃ¥ mikrovolt*S. Derefter ganges med 
        % 1000ms/s for at omregne enheden til mikroVolt*ms.   
        
            if P_ecg_aligned(i)>= 0                 
        %    sum_p_loop(nr) = sum_p_loop(nr) +
        %    10^6*(P_ecg_aligned(i))/(XML.TestInfo.ECGSampleBase * 1000);       Den gamle metode
            sum_p_loop(nr) = sum_p_loop(nr) + (trapz(P_ecg_aligned(i-1:i))/500)*1000;
            end 
            if P_ecg_aligned(i)< 0                  % Her summeres den negative del af p-loopet
        %    sum_p_inv_loop(nr) = sum_p_inv_loop(nr) + 10^6*(P_ecg_aligned(i))/(XML.TestInfo.ECGSampleBase * 1000);    % den gamle metode    
            sum_p_inv_loop(nr) = sum_p_inv_loop(nr) + (trapz(P_ecg_aligned(i-1:i))/500)*1000;
            end  
        end
        % Enheden for sum_p_loop og sum_p_inv_loop er mikroVolt*ms. sum_p_inv_loop
        % kan vÃ¦re positiv, da udregning regner integralet mellem to punkter af
        % gangen. Hvis punkterne er pÃ¥ hver side af 0, vil dette pÃ¥virke
        % resultatet.
        
        % Detection af bifasisk p-bÃ¸lge
        biphasic_p_wave(nr) = 0;                    % Initialiseres og sÃ¦ttes til 0.
        if  sum_p_inv_loop(nr)<=-160                % Treshold er 160 mikroV*mS           
            biphasic_p_wave(nr)=1;
        end 
        if  sum_p_inv_loop(nr)>-160
            biphasic_p_wave(nr)=0;
        end 
        
        % Detection af partiel IAB - detectionen af p-IAB er den samme for alle
        % leads. 
        p_iab = 0;                                  %initialiseres og sÃ¦ttes til 0.
        if length(P_loop)*2 >=120                   % Treshold er 120 ms for detection af partiel IAB   
            p_iab = 1;                              % Returnerer 1, hvis sandt 
        end 
        if length(P_loop)*2 < 120
            p_iab = 0;                              % Returnerer 0 hvis falsk
        end
        end
        end
        
    end
    
    methods (Access = private)
        
        function plotPrecordialElectrodes(app)
        axis_size = app.str + 10;% distance away from origo to plot electrode
        v2 = [0; -axis_size]; %First electrode which is the starting point
        precordial_angles = [-30 0 30 60 90 120]; %Degrees to rotate
        precordial_elec = zeros(2,length(precordial_angles)); %Vector with the coordinates for each electrode
        for i=1:length(precordial_angles)
            phi = precordial_angles(i).*(pi/180);
            R = [cos(phi) -sin(phi); sin(phi) cos(phi)];
            
            precordial_elec(:,i) = R*v2; %Calculate new rotation.
            scatter3(app.threedimUIAxes, precordial_elec(1,i),precordial_elec(2,i),0,'sr','filled')
            text(app.threedimUIAxes, precordial_elec(1,i)+2,precordial_elec(2,i)+2,0,"V"+i)
        end
            
        end
        
        function plotFrontalElectrodes(app)
        axis_size = app.str + 10;% distance away from origo to plot electrode
        I = [axis_size;0];
        frontal_angles = [0 -30 -60 -90 -120 30];
        frontal_elec = zeros(2,length(frontal_angles));
        for i=1:length(frontal_angles)
            phi = frontal_angles(i).*(pi/180);
            R = [cos(phi) -sin(phi); sin(phi) cos(phi)];
            
            frontal_elec(:,i) = R*I;
            scatter3(app.threedimUIAxes, frontal_elec(1,i),0,frontal_elec(2,i),'vk','filled')
            switch i
                case 1
                    text(app.threedimUIAxes,frontal_elec(1,i)+4,0,frontal_elec(2,i)+4,"I")
                case 2
                    text(app.threedimUIAxes, frontal_elec(1,i)+4,0,frontal_elec(2,i)+4,"aVR")
                case 3    
                    text(app.threedimUIAxes,frontal_elec(1,i)+4,0,frontal_elec(2,i)+4,"II")
                case 4
                    text(app.threedimUIAxes,frontal_elec(1,i)+4,0,frontal_elec(2,i)+4,"aVF")
                case 5
                    text(app.threedimUIAxes,frontal_elec(1,i)+4,0,frontal_elec(2,i)+4,"III")
                case 6
                    text(app.threedimUIAxes,frontal_elec(1,i)+4,0,frontal_elec(2,i)+4,"aVL")
            end
        end

        end
        
        function RefreshPlots(app)
            
            app.statsCheckBoxBiphasic.Value = 0;%Reset checkbox for biphase
            
            app.calcVCG;
            app.plot2d;
            app.plot3d;
            app.plotStatsTab;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath("S:\Matlab XML reader")
            testfiledir='S:\Testdata\';
            app.xmlfiles=dir(fullfile(testfiledir,'*xml'));
            
            app.Lamp.Color=[0.39 0.83 0.07];
            
            app.calcVCG;
            app.plot2d;
            app.plot3d;
            app.plotStatsTab;
        end

        % Value changed function: ViewDropDown
        function ViewDropDownValueChanged(app, event)
            value = app.ViewDropDown.Value;
            
            if value=="All"
                app.On=1;
                app.Off=600;
                app.axistype=app.allaxis;
                app.str = 1100; %Axis size of cross to fit the plot
            end
            
            if value=="QRS"
                app.On=app.XML.TestInfo.QOnset;
                app.Off=app.XML.TestInfo.QOffset;
                app.axistype=app.qrsaxis;
                app.str = 1100; %Axis size of cross to fit the plot
            end
            
            if value=="P"
                app.On=app.XML.TestInfo.POnset;
                app.Off=app.XML.TestInfo.POffset;
                app.axistype=app.paxis;
                app.str = 200; %Axis size of cross to fit the plot
            end
            
            app.RefreshPlots;
        end

        % Value changed function: CurrentplotSpinner
        function CurrentplotSpinnerValueChanged(app, event)
            value = app.CurrentplotSpinner.Value;
            
            app.RefreshPlots;
        end

        % Value changed function: LinetypeDropDown
        function LinetypeDropDownValueChanged(app, event)
            value = app.LinetypeDropDown.Value;
                       
            if value=="o"
                app.ltindex=1;
            end
            
            if value=="."
                app.ltindex=2;
            end
            
            if value=="-"
                app.ltindex=3;
            end
            
            app.RefreshPlots;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DTab_2 = uitab(app.TabGroup);
            app.DTab_2.Title = '3D';
            app.DplaneLabel = uilabel(app.DTab_2);
            app.DplaneLabel.Text = '3D plane';
            app.threedimUIAxes = uiaxes(app.DTab_2);
            title(app.threedimUIAxes, '3D')
            xlabel(app.threedimUIAxes, 'X')
            ylabel(app.threedimUIAxes, 'Y')
            app.DTab = uitab(app.TabGroup);
            app.DTab.Title = '2D';
            app.DplanesLabel = uilabel(app.DTab);
            app.DplanesLabel.Text = '2D planes';
            app.frontal = uiaxes(app.DTab);
            title(app.frontal, 'Frontal')
            xlabel(app.frontal, 'X')
            ylabel(app.frontal, 'Y')
            app.sagital = uiaxes(app.DTab);
            title(app.sagital, 'Sagital')
            xlabel(app.sagital, 'Y')
            ylabel(app.sagital, 'Z')
            app.transversal = uiaxes(app.DTab);
            title(app.transversal, 'Transversal')
            xlabel(app.transversal, 'X')
            ylabel(app.transversal, 'Z')
            app.Lamp = uilamp(app.DTab);
            app.DataloadedLabel = uilabel(app.DTab);
            app.DataloadedLabel.Text = 'Data loaded';
            app.StatsTab = uitab(app.TabGroup);
            app.StatsTab.Title = 'Stats';
            app.GridLayout2 = uigridlayout(app.StatsTab);
            app.ECGleadsPanel = uipanel(app.GridLayout2);
            app.ECGleadsPanel.Title = 'ECG leads';
            app.GridLayout = uigridlayout(app.ECGleadsPanel);
            app.statsUIAxes1 = uiaxes(app.GridLayout);
            title(app.statsUIAxes1, 'Title')
            xlabel(app.statsUIAxes1, 'X')
            ylabel(app.statsUIAxes1, 'Y')
            app.statsUIAxes2 = uiaxes(app.GridLayout);
            title(app.statsUIAxes2, 'Title')
            xlabel(app.statsUIAxes2, 'X')
            ylabel(app.statsUIAxes2, 'Y')
            app.statsUIAxes3 = uiaxes(app.GridLayout);
            title(app.statsUIAxes3, 'Title')
            xlabel(app.statsUIAxes3, 'X')
            ylabel(app.statsUIAxes3, 'Y')
            app.statsUIAxes4 = uiaxes(app.GridLayout);
            title(app.statsUIAxes4, 'Title')
            xlabel(app.statsUIAxes4, 'X')
            ylabel(app.statsUIAxes4, 'Y')
            app.statsUIAxes5 = uiaxes(app.GridLayout);
            title(app.statsUIAxes5, 'Title')
            xlabel(app.statsUIAxes5, 'X')
            ylabel(app.statsUIAxes5, 'Y')
            app.statsUIAxes6 = uiaxes(app.GridLayout);
            title(app.statsUIAxes6, 'Title')
            xlabel(app.statsUIAxes6, 'X')
            ylabel(app.statsUIAxes6, 'Y')
            app.statsCheckBoxBiphasic = uicheckbox(app.GridLayout2);
            app.statsCheckBoxBiphasic.Enable = 'off';
            app.statsCheckBoxBiphasic.Text = {''; ''};
            app.BiphasicPwaveLabel = uilabel(app.GridLayout2);
            app.BiphasicPwaveLabel.Text = 'Biphasic P-wave';
            app.InleadsLabel = uilabel(app.GridLayout2);
            app.InleadsLabel.Text = 'In leads:';
            app.leadsLabel = uilabel(app.GridLayout2);
            app.CurrentplotSpinnerLabel = uilabel(app.UIFigure);
            app.CurrentplotSpinnerLabel.Text = 'Current plot';
            app.CurrentplotSpinner = uispinner(app.UIFigure);
            app.CurrentplotSpinner.ValueChangedFcn = createCallbackFcn(app, @CurrentplotSpinnerValueChanged, true);
            app.CurrentplotSpinner.Value = 1;
            app.ViewDropDownLabel = uilabel(app.UIFigure);
            app.ViewDropDownLabel.Text = 'View';
            app.ViewDropDown = uidropdown(app.UIFigure);
            app.ViewDropDown.Items = {'All', 'QRS', 'P'};
            app.ViewDropDown.ValueChangedFcn = createCallbackFcn(app, @ViewDropDownValueChanged, true);
            app.ViewDropDown.Value = 'All';
            app.LinetypeDropDownLabel = uilabel(app.UIFigure);
            app.LinetypeDropDownLabel.Text = 'Linetype';
            app.LinetypeDropDown = uidropdown(app.UIFigure);
            app.LinetypeDropDown.Items = {'o', '.', '-'};
            app.LinetypeDropDown.ValueChangedFcn = createCallbackFcn(app, @LinetypeDropDownValueChanged, true);
            app.LinetypeDropDown.Value = 'o';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VCGapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end